classdef NASA_Test_1_LivestreamTest < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SetupPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BasicChecksPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        CheckButton                     matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        WiringCheckLabel                matlab.ui.control.Label
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        TubingCheckedLabel              matlab.ui.control.Label
        LivestreaminAppLabel            matlab.ui.control.Label
        FlowSettingsPanel               matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        O2molSpinnerLabel               matlab.ui.control.Label
        O2molSpinner                    matlab.ui.control.Spinner
        N2molSpinnerLabel               matlab.ui.control.Label
        N2molSpinner                    matlab.ui.control.Spinner
        MFC1RangeIDEditFieldLabel       matlab.ui.control.Label
        MFC1RangeIDEditField            matlab.ui.control.EditField
        MFC2RangeIDEditFieldLabel       matlab.ui.control.Label
        MFC2RangeIDEditField            matlab.ui.control.EditField
        ReadyButton                     matlab.ui.control.Button
        InletAreacm2SpinnerLabel        matlab.ui.control.Label
        InletAreacm2Spinner             matlab.ui.control.Spinner
        CODE_NAME2                      matlab.ui.control.DropDown
        VolumetricFlowRateSLPMSpinner   matlab.ui.control.Spinner
        WeightScaleResetPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ResetScaleButton                matlab.ui.control.Button
        ChamberBackfillPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        psiaEditFieldLabel              matlab.ui.control.Label
        psiaEditField                   matlab.ui.control.NumericEditField
        PressureLabel                   matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        EvacuateChamberButton           matlab.ui.control.StateButton
        RefillChamberButton             matlab.ui.control.StateButton
        StartRunPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        IgnitionButton                  matlab.ui.control.StateButton
        StartRecordingDataButton        matlab.ui.control.StateButton
        RetractIgniterButton            matlab.ui.control.StateButton
        DataTranscriptionPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ChooseParentDirectoryButton     matlab.ui.control.Button
        FolderSelectedEditFieldLabel    matlab.ui.control.Label
        FolderSelectedEditField         matlab.ui.control.EditField
        TestNameEditFieldLabel          matlab.ui.control.Label
        TestNameEditField               matlab.ui.control.EditField
        CenterPanel                     matlab.ui.container.Panel
        LivestreamPanel                 matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        LiveGraphsTab                   matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        TemperatureTab                  matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        RadiativeFluxTab                matlab.ui.container.Tab
        GridLayout15_2                  matlab.ui.container.GridLayout
        UIAxes_2                        matlab.ui.control.UIAxes
        ChangeinWeightTab               matlab.ui.container.Tab
        GridLayout15_3                  matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        LiveTables                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        VitalsPanel                     matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        PressureSensorPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ChamberPressureEditFieldLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        psiaLabel                       matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        SensorTemperatureEditFieldLabel  matlab.ui.control.Label
        SensorTemperatureEditField      matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        PaperIgnitedPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        PaperIgnitedCheckBox            matlab.ui.control.CheckBox
        MassFlowControllersPanel        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        VolumetricFlowO2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowO2EditField       matlab.ui.control.NumericEditField
        SLPMLabel                       matlab.ui.control.Label
        VolumetricFlowN2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowN2EditField       matlab.ui.control.NumericEditField
        SLPMLabel_2                     matlab.ui.control.Label
        MassFlowO2EditFieldLabel        matlab.ui.control.Label
        MassFlowO2EditField             matlab.ui.control.NumericEditField
        MassFlowN2EditFieldLabel        matlab.ui.control.Label
        MassFlowN2EditField             matlab.ui.control.NumericEditField
        VolumetricFlowTotEditFieldLabel  matlab.ui.control.Label
        VolumetricFlowTotEditField      matlab.ui.control.NumericEditField
        MassFlowTotalEditFieldLabel     matlab.ui.control.Label
        MassFlowTotalEditField          matlab.ui.control.NumericEditField
        InletVelocityEditFieldLabel     matlab.ui.control.Label
        InletVelocityEditField          matlab.ui.control.NumericEditField
        SLPMLabel_3                     matlab.ui.control.Label
        ULabel_4                        matlab.ui.control.Label
        ULabel_5                        matlab.ui.control.Label
        ULabel_6                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        UpstreamPressureEditFieldLabel  matlab.ui.control.Label
        UpstreamPressureEditField       matlab.ui.control.NumericEditField
        UpstreamPressureEditField_2Label  matlab.ui.control.Label
        UpstreamPressureEditField_2     matlab.ui.control.NumericEditField
        psiaLabel_3                     matlab.ui.control.Label
        psiaLabel_2                     matlab.ui.control.Label
        StopRunButton                   matlab.ui.control.StateButton
        ConsoleTextAreaLabel            matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        f % Description
        ar %
        running %
        addrs%
        adc %
        WireReady
        TubeReady
        FlowReady
        ScaleReady
        Tab
        AllData
        Sensors
        Sensor_report
        Pressure_running
        Flow02
        FlowN2
        MFC1_ID
        MFC2_ID
        fullname
        cam
        dataRes
        dir
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear; clc;
            app.FlowReady = 0;
            app.TubeReady = 0;
            app.ScaleReady = 0;
            app.WireReady = 0;
            app.addrs = 1;
        end

        % Callback function
        function ReadyButtonPushed(app, event)
            set(app.ChamberBackfillPanel,'visible','on')
            set(app.StartRunPanel,'visible','on')
        end

        % Button pushed function: ChooseParentDirectoryButton
        function ChooseParentDirectoryButtonPushed(app, event)
            selpath =  uigetdir;
            app.FolderSelectedEditField.Value = selpath;
        end

        % Button pushed function: CheckButton
        function CheckButtonPushed(app, event)
            %Change to whatever COM port the Arduino is connected to
            %This is the # of seconds the app waits between measurements
            app.dataRes = .1;
            
            %app.FlowReady = 0;
            %app.TubeReady = 0;
            %app.ScaleReady = 0;
            app.WireReady = 1;
            
            app.Lamp.Color = 'green';
            
            %Initialize webcam -- Change name to whatever cam you are using
            if app.CheckBox_2.Value
                app.cam = webcam('USB2.0 HD UVC WebCam');
            end


            %Run livestream
            
        end

        % Callback function
        function CheckButton_2Pushed(app, event)
            app.TubeReady = 1;
            app.Lamp_2.Color = 'green';
            
        end

        % Value changed function: O2molSpinner
        function O2molSpinnerValueChanged(app, event)
            value = app.O2molSpinner.Value;
            if (value<=100) && (value>=0)
                app.N2molSpinner.Value = 100-value;
            end
            
            %Need to calculate the flow for O2 and N2 here
            
            app.FlowReady = 1;
            
        end

        % Button pushed function: ResetScaleButton
        function ResetScaleButtonPushed(app, event)
            write(app.s,'t',"uint8"); 
            
            app.ScaleReady = 1;
                        
            
        end

        % Value changed function: PaperIgnitedCheckBox
        function PaperIgnitedCheckBoxValueChanged(app, event)
            value = app.PaperIgnitedCheckBox.Value;
            
        end

        % Value changed function: EvacuateChamberButton
        function EvacuateChamberButtonValueChanged(app, event)
            %{
            This is going to initiate the main event loop.
            
            From the loop, all other state buttons will be checked and resulting actions will be exectuted.        
            %}
            value = app.EvacuateChamberButton.Value;
            
            app.running = 1;
            
            %Initialize camera writer
            if app.CheckBox_2.Value
                vidWriter = VideoWriter(sprintf("%s%s",app.fullname,".mp4"));
                open(vidWriter);
            end
            
            %Initiate Data matrix: [T1,T2,P,T_P,W,L]
            data = [];
            
            %flow is not set yet
            flowS = false;
            
            MAXAMBIENTPRESSURE = 0; %kPa
            MAXAMBIENTTEMPERATURE = 0; %kPa
            REFILLPRESSURE = 0; %kPa
            DESIREDPRESSURE1 = 0; %kPa
            DESIREDPRESSURE2 = 0; %kPa
            
            t = seconds(0);            
            i = 0;
            while app.running                
                %Take a snapshot, write it to the mp4 file, and display it
                %in the GUI
                if app.CheckBox_2.Value
                    img = snapshot(app.cam);
                    writeVideo(vidWriter,img);
                    app.Image.ImageSource = img;  
                end
                
                %This button breaks the event loop and the arduino's event loop. 
                %Button Default is off 
                if app.StopRunButton.Value
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; sprintf("%s","Stopping all processes...")];
                    app.running = 0;
                    
                    %Close the vide writer and camera
                    if app.CheckBox_2.Value
                        close(vidWriter);
                        clear app.cam;
                    end
                end
                
                %Pause a certain number of seconds
                pause(app.dataRes)
                
            end


        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed2(app, event)
            %Convert the percentages to fractions
            a = app.O2molSpinner.Value / 100;
            b = app.N2molSpinner.Value / 100;
            
            if app.CODE_NAME2.Value == "Volumetric Flow Rate(SLPM)"               
                %Calculate the volumetric flow rate for both the O2 and N2 MFC's
                app.Flow02 = app.VolumetricFlowRateSLPMSpinner.Value * a;
                app.FlowN2 = app.VolumetricFlowRateSLPMSpinner.Value * b;
            else
                Vdot = app.VolumetricFlowRateSLPMSpinner.Value * app.InletAreacm2Spinner.Value * .01;
                app.Flow02 = Vdot * a;
                app.FlowN2 = Vdot * b;
            end
            
            %Print new flow rates to console            
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s","Flow of O2 set to: ", num2str(app.Flow02), " SLPM")];           
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s","Flow of N2 set to: ", num2str(app.FlowN2), " SLPM")];
            
            %Based on the MFC Range/ID given assigns the appropriate ID to
            %the appropriate MFC
            if app.MFC1RangeIDEditField.Value == "A" || app.MFC1RangeIDEditField.Value == "B" ...
                    || app.MFC1RangeIDEditField.Value == "C" || app.MFC1RangeIDEditField.Value == "D"... 
                    || app.MFC1RangeIDEditField.Value == "E" || app.MFC1RangeIDEditField.Value == "F"
                app.MFC1_ID = app.MFC1RangeIDEditField.Value; 
            elseif app.MFC1RangeIDEditField.Value == "50"
                app.MFC1_ID = "A";
            elseif app.MFC1RangeIDEditField.Value == "1"
                app.MFC1_ID = "C";
            elseif app.MFC1RangeIDEditField.Value == "5"
                app.MFC1_ID = "E";
            end
            
            if app.MFC2RangeIDEditField.Value == "A" || app.MFC2RangeIDEditField.Value == "B" ...
                    || app.MFC2RangeIDEditField.Value == "C" || app.MFC2RangeIDEditField.Value == "D"... 
                    || app.MFC2RangeIDEditField.Value == "E" || app.MFC2RangeIDEditField.Value == "F"
                app.MFC2_ID = app.MFC2RangeIDEditField.Value; 
            elseif app.MFC2RangeIDEditField.Value == "50"
                if app.MFC1_ID == "A"
                    app.MFC2_ID = "B";
                else
                    app.MFC2_ID = "A";
                end                
            elseif app.MFC2RangeIDEditField.Value == "1"
                if app.MFC1_ID == "C"
                    app.MFC1_ID = "D";
                else
                    app.MFC1_ID = "C";
                end
            elseif app.MFC2RangeIDEditField.Value == "5"
                if app.MFC1_ID == "E"
                    app.MFC1_ID = "F";
                else
                    app.MFC1_ID = "E";
                end
            end
            
        end

        % Value changed function: TestNameEditField
        function TestNameEditFieldValueChanged(app, event)
            value = app.TestNameEditField.Value;
            
            %Sets the name of all files in the folder
            if ~isempty(app.TestNameEditField.Value)                
                %Make a directory for all the data from the test
                app.dir = mkdir(app.FolderSelectedEditField.Value, value);
                
                app.fullname = sprintf("%s\\%s\\%s",app.FolderSelectedEditField.Value,value,value);
                
                app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s%s","Made new directory ", value, " inside ", app.FolderSelectedEditField.Value)];
                
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {970, 970, 970};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {970, 970};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x', 297};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetupPanel = uipanel(app.LeftPanel);
            app.SetupPanel.Title = 'Setup';
            app.GridLayout2 = uigridlayout(app.SetupPanel);
            app.BasicChecksPanel = uipanel(app.GridLayout2);
            app.BasicChecksPanel.Title = 'Basic Checks';
            app.GridLayout3 = uigridlayout(app.BasicChecksPanel);
            app.CheckButton = uibutton(app.GridLayout3, 'push');
            app.CheckButton.ButtonPushedFcn = createCallbackFcn(app, @CheckButtonPushed, true);
            app.CheckButton.Text = 'Check';
            app.Lamp = uilamp(app.GridLayout3);
            app.WiringCheckLabel = uilabel(app.GridLayout3);
            app.WiringCheckLabel.Text = 'Wiring Check';
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.GridLayout3);
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.TubingCheckedLabel = uilabel(app.GridLayout3);
            app.TubingCheckedLabel.Text = 'Tubing Checked';
            app.LivestreaminAppLabel = uilabel(app.GridLayout3);
            app.LivestreaminAppLabel.Text = 'Livestream in App';
            app.FlowSettingsPanel = uipanel(app.GridLayout2);
            app.FlowSettingsPanel.Title = 'Flow Settings';
            app.GridLayout7 = uigridlayout(app.FlowSettingsPanel);
            app.O2molSpinnerLabel = uilabel(app.GridLayout7);
            app.O2molSpinnerLabel.Text = 'O2 mol%';
            app.O2molSpinner = uispinner(app.GridLayout7);
            app.O2molSpinner.ValueChangedFcn = createCallbackFcn(app, @O2molSpinnerValueChanged, true);
            app.O2molSpinner.Value = 35;
            app.N2molSpinnerLabel = uilabel(app.GridLayout7);
            app.N2molSpinnerLabel.Text = 'N2 mol%';
            app.N2molSpinner = uispinner(app.GridLayout7);
            app.N2molSpinner.Limits = [0 100];
            app.N2molSpinner.Value = 65;
            app.MFC1RangeIDEditFieldLabel = uilabel(app.GridLayout7);
            app.MFC1RangeIDEditFieldLabel.Text = 'MFC 1 Range/ID';
            app.MFC1RangeIDEditField = uieditfield(app.GridLayout7, 'text');
            app.MFC1RangeIDEditField.Value = 'A';
            app.MFC2RangeIDEditFieldLabel = uilabel(app.GridLayout7);
            app.MFC2RangeIDEditFieldLabel.Text = 'MFC 2 Range/ID';
            app.MFC2RangeIDEditField = uieditfield(app.GridLayout7, 'text');
            app.MFC2RangeIDEditField.Value = 'B';
            app.ReadyButton = uibutton(app.GridLayout7, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed2, true);
            app.ReadyButton.Text = 'Ready';
            app.InletAreacm2SpinnerLabel = uilabel(app.GridLayout7);
            app.InletAreacm2SpinnerLabel.Text = 'Inlet Area(cm^2)';
            app.InletAreacm2Spinner = uispinner(app.GridLayout7);
            app.InletAreacm2Spinner.Value = 5;
            app.CODE_NAME2 = uidropdown(app.GridLayout7);
            app.CODE_NAME2.Items = {'Velocity(m/s)', 'Volumetric Flow Rate(SLPM)', ''};
            app.CODE_NAME2.Value = 'Volumetric Flow Rate(SLPM)';
            app.VolumetricFlowRateSLPMSpinner = uispinner(app.GridLayout7);
            app.VolumetricFlowRateSLPMSpinner.Step = 0.1;
            app.WeightScaleResetPanel = uipanel(app.GridLayout2);
            app.WeightScaleResetPanel.Title = 'Weight Scale Reset';
            app.GridLayout5 = uigridlayout(app.WeightScaleResetPanel);
            app.ResetScaleButton = uibutton(app.GridLayout5, 'push');
            app.ResetScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScaleButtonPushed, true);
            app.ResetScaleButton.Text = 'Reset Scale';
            app.ChamberBackfillPanel = uipanel(app.GridLayout2);
            app.ChamberBackfillPanel.Title = 'Chamber Backfill';
            app.GridLayout6 = uigridlayout(app.ChamberBackfillPanel);
            app.psiaEditFieldLabel = uilabel(app.GridLayout6);
            app.psiaEditFieldLabel.Text = 'psia';
            app.psiaEditField = uieditfield(app.GridLayout6, 'numeric');
            app.PressureLabel = uilabel(app.GridLayout6);
            app.PressureLabel.Text = 'Pressure:';
            app.Lamp_3 = uilamp(app.GridLayout6);
            app.Lamp_4 = uilamp(app.GridLayout6);
            app.EvacuateChamberButton = uibutton(app.GridLayout6, 'state');
            app.EvacuateChamberButton.ValueChangedFcn = createCallbackFcn(app, @EvacuateChamberButtonValueChanged, true);
            app.EvacuateChamberButton.Text = 'Evacuate Chamber';
            app.RefillChamberButton = uibutton(app.GridLayout6, 'state');
            app.RefillChamberButton.Text = 'Refill Chamber';
            app.StartRunPanel = uipanel(app.GridLayout2);
            app.StartRunPanel.Title = 'Start Run';
            app.GridLayout8 = uigridlayout(app.StartRunPanel);
            app.Lamp_5 = uilamp(app.GridLayout8);
            app.Lamp_6 = uilamp(app.GridLayout8);
            app.Lamp_9 = uilamp(app.GridLayout8);
            app.IgnitionButton = uibutton(app.GridLayout8, 'state');
            app.IgnitionButton.Text = 'Ignition';
            app.StartRecordingDataButton = uibutton(app.GridLayout8, 'state');
            app.StartRecordingDataButton.Text = 'Start Recording Data';
            app.RetractIgniterButton = uibutton(app.GridLayout8, 'state');
            app.RetractIgniterButton.Text = 'Retract Igniter';
            app.DataTranscriptionPanel = uipanel(app.GridLayout2);
            app.DataTranscriptionPanel.Title = 'Data Transcription';
            app.GridLayout9 = uigridlayout(app.DataTranscriptionPanel);
            app.ChooseParentDirectoryButton = uibutton(app.GridLayout9, 'push');
            app.ChooseParentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseParentDirectoryButtonPushed, true);
            app.ChooseParentDirectoryButton.Text = 'Choose Parent Directory';
            app.FolderSelectedEditFieldLabel = uilabel(app.GridLayout9);
            app.FolderSelectedEditFieldLabel.Text = 'Folder Selected:';
            app.FolderSelectedEditField = uieditfield(app.GridLayout9, 'text');
            app.TestNameEditFieldLabel = uilabel(app.GridLayout9);
            app.TestNameEditFieldLabel.Text = 'Test Name:';
            app.TestNameEditField = uieditfield(app.GridLayout9, 'text');
            app.TestNameEditField.ValueChangedFcn = createCallbackFcn(app, @TestNameEditFieldValueChanged, true);
            app.CenterPanel = uipanel(app.GridLayout);
            app.LivestreamPanel = uipanel(app.CenterPanel);
            app.LivestreamPanel.Title = 'Livestream';
            app.Image = uiimage(app.LivestreamPanel);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.LiveGraphsTab = uitab(app.TabGroup);
            app.LiveGraphsTab.Title = 'Live Graphs';
            app.GridLayout14 = uigridlayout(app.LiveGraphsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout14);
            app.TemperatureTab = uitab(app.TabGroup2);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout15 = uigridlayout(app.TemperatureTab);
            app.UIAxes = uiaxes(app.GridLayout15);
            title(app.UIAxes, 'Temperature vs Time')
            xlabel(app.UIAxes, 't')
            ylabel(app.UIAxes, 'T(C)')
            app.RadiativeFluxTab = uitab(app.TabGroup2);
            app.RadiativeFluxTab.Title = 'Radiative Flux';
            app.GridLayout15_2 = uigridlayout(app.RadiativeFluxTab);
            app.UIAxes_2 = uiaxes(app.GridLayout15_2);
            title(app.UIAxes_2, 'Radiative Flux vs Time')
            xlabel(app.UIAxes_2, 't')
            ylabel(app.UIAxes_2, 'Radiaitive Flux(mW/cm^2)')
            app.ChangeinWeightTab = uitab(app.TabGroup2);
            app.ChangeinWeightTab.Title = 'Change in Weight';
            app.GridLayout15_3 = uigridlayout(app.ChangeinWeightTab);
            app.UIAxes2 = uiaxes(app.GridLayout15_3);
            title(app.UIAxes2, 'Change in Weight vs Time')
            xlabel(app.UIAxes2, 't')
            ylabel(app.UIAxes2, 'Change in Weight(g)')
            app.LiveTables = uitab(app.TabGroup);
            app.LiveTables.Title = 'Live Tables';
            app.GridLayout16 = uigridlayout(app.LiveTables);
            app.UITable = uitable(app.GridLayout16);
            app.UITable.ColumnName = {'Time'; 'Temperature'; 'Radiative Flux'; 'Change in Weight'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.VitalsPanel = uipanel(app.RightPanel);
            app.VitalsPanel.Title = 'Vitals';
            app.GridLayout10 = uigridlayout(app.VitalsPanel);
            app.PressureSensorPanel = uipanel(app.GridLayout10);
            app.PressureSensorPanel.Title = 'Pressure Sensor';
            app.GridLayout11 = uigridlayout(app.PressureSensorPanel);
            app.ChamberPressureEditFieldLabel = uilabel(app.GridLayout11);
            app.ChamberPressureEditFieldLabel.Text = 'Chamber Pressure';
            app.ChamberPressureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.psiaLabel = uilabel(app.GridLayout11);
            app.psiaLabel.Text = 'psia';
            app.Lamp_7 = uilamp(app.GridLayout11);
            app.SensorTemperatureEditFieldLabel = uilabel(app.GridLayout11);
            app.SensorTemperatureEditFieldLabel.Text = 'Sensor Temperature';
            app.SensorTemperatureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.CLabel = uilabel(app.GridLayout11);
            app.CLabel.Text = 'C';
            app.Lamp_8 = uilamp(app.GridLayout11);
            app.PaperIgnitedPanel = uipanel(app.GridLayout10);
            app.PaperIgnitedPanel.Title = 'Paper Ignited';
            app.GridLayout12 = uigridlayout(app.PaperIgnitedPanel);
            app.PaperIgnitedCheckBox = uicheckbox(app.GridLayout12);
            app.PaperIgnitedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PaperIgnitedCheckBoxValueChanged, true);
            app.PaperIgnitedCheckBox.Text = 'Paper Ignited';
            app.MassFlowControllersPanel = uipanel(app.GridLayout10);
            app.MassFlowControllersPanel.Title = 'Mass Flow Controllers';
            app.GridLayout13 = uigridlayout(app.MassFlowControllersPanel);
            app.VolumetricFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowO2EditFieldLabel.Text = 'Volumetric Flow O2';
            app.VolumetricFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel = uilabel(app.GridLayout13);
            app.SLPMLabel.Text = 'SLPM';
            app.VolumetricFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowN2EditFieldLabel.Text = 'Volumetric Flow N2';
            app.VolumetricFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel_2 = uilabel(app.GridLayout13);
            app.SLPMLabel_2.Text = 'SLPM';
            app.MassFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowO2EditFieldLabel.Text = 'Mass Flow O2';
            app.MassFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowN2EditFieldLabel.Text = 'Mass Flow N2';
            app.MassFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.VolumetricFlowTotEditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowTotEditFieldLabel.Text = 'Volumetric Flow Tot.';
            app.VolumetricFlowTotEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowTotalEditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowTotalEditFieldLabel.Text = 'Mass Flow Total';
            app.MassFlowTotalEditField = uieditfield(app.GridLayout13, 'numeric');
            app.InletVelocityEditFieldLabel = uilabel(app.GridLayout13);
            app.InletVelocityEditFieldLabel.Text = 'Inlet Velocity';
            app.InletVelocityEditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel_3 = uilabel(app.GridLayout13);
            app.SLPMLabel_3.Text = 'SLPM';
            app.ULabel_4 = uilabel(app.GridLayout13);
            app.ULabel_4.Text = 'U';
            app.ULabel_5 = uilabel(app.GridLayout13);
            app.ULabel_5.Text = 'U';
            app.ULabel_6 = uilabel(app.GridLayout13);
            app.ULabel_6.Text = 'U';
            app.msLabel = uilabel(app.GridLayout13);
            app.msLabel.Text = 'm/s';
            app.UpstreamPressureEditFieldLabel = uilabel(app.GridLayout13);
            app.UpstreamPressureEditFieldLabel.Text = 'Upstream Pressure';
            app.UpstreamPressureEditField = uieditfield(app.GridLayout13, 'numeric');
            app.UpstreamPressureEditField_2Label = uilabel(app.GridLayout13);
            app.UpstreamPressureEditField_2Label.Text = 'Upstream Pressure';
            app.UpstreamPressureEditField_2 = uieditfield(app.GridLayout13, 'numeric');
            app.psiaLabel_3 = uilabel(app.GridLayout13);
            app.psiaLabel_3.Text = 'psia';
            app.psiaLabel_2 = uilabel(app.GridLayout13);
            app.psiaLabel_2.Text = 'psia';
            app.StopRunButton = uibutton(app.RightPanel, 'state');
            app.StopRunButton.Text = 'Stop Run';
            app.ConsoleTextAreaLabel = uilabel(app.RightPanel);
            app.ConsoleTextAreaLabel.Text = 'Console:';
            app.ConsoleTextArea = uitextarea(app.RightPanel);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Value = {'Welcome!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NASA_Test_1_LivestreamTest

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end