classdef NASA_Test_1_MK2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout                      matlab.ui.container.GridLayout
        LeftPanel                       matlab.ui.container.Panel
        SetupPanel                      matlab.ui.container.Panel
        GridLayout2                     matlab.ui.container.GridLayout
        BasicChecksPanel                matlab.ui.container.Panel
        GridLayout3                     matlab.ui.container.GridLayout
        WiringCheckButton               matlab.ui.control.Button
        Lamp                            matlab.ui.control.Lamp
        CheckBox                        matlab.ui.control.CheckBox
        CheckBox_2                      matlab.ui.control.CheckBox
        TubingCheckedLabel              matlab.ui.control.Label
        LivestreaminAppLabel            matlab.ui.control.Label
        FlowSettingsPanel               matlab.ui.container.Panel
        GridLayout7                     matlab.ui.container.GridLayout
        O2molSpinnerLabel               matlab.ui.control.Label
        O2molSpinner                    matlab.ui.control.Spinner
        N2molSpinnerLabel               matlab.ui.control.Label
        N2molSpinner                    matlab.ui.control.Spinner
        MFC1RangeIDEditFieldLabel       matlab.ui.control.Label
        MFC1RangeIDEditField            matlab.ui.control.EditField
        MFC2RangeIDEditFieldLabel       matlab.ui.control.Label
        MFC2RangeIDEditField            matlab.ui.control.EditField
        ReadyButton                     matlab.ui.control.Button
        InletAreacm2SpinnerLabel        matlab.ui.control.Label
        InletAreacm2Spinner             matlab.ui.control.Spinner
        CODE_NAME2                      matlab.ui.control.DropDown
        VolumetricFlowRateSLPMSpinner   matlab.ui.control.Spinner
        WeightScaleResetPanel           matlab.ui.container.Panel
        GridLayout5                     matlab.ui.container.GridLayout
        ResetScaleButton                matlab.ui.control.Button
        DataTranscriptionPanel          matlab.ui.container.Panel
        GridLayout9                     matlab.ui.container.GridLayout
        ChooseParentDirectoryButton     matlab.ui.control.Button
        FolderSelectedEditFieldLabel    matlab.ui.control.Label
        FolderSelectedEditField         matlab.ui.control.EditField
        TestNameEditFieldLabel          matlab.ui.control.Label
        TestNameEditField               matlab.ui.control.EditField
        RunPanel                        matlab.ui.container.Panel
        GridLayout17                    matlab.ui.container.GridLayout
        ChamberBackfillPanel            matlab.ui.container.Panel
        GridLayout6                     matlab.ui.container.GridLayout
        psiaEditFieldLabel              matlab.ui.control.Label
        psiaEditField                   matlab.ui.control.NumericEditField
        PressureLabel                   matlab.ui.control.Label
        Lamp_3                          matlab.ui.control.Lamp
        Lamp_4                          matlab.ui.control.Lamp
        EvacuateChamberButton           matlab.ui.control.StateButton
        RefillChamberButton             matlab.ui.control.StateButton
        StartRunPanel                   matlab.ui.container.Panel
        GridLayout8                     matlab.ui.container.GridLayout
        Lamp_5                          matlab.ui.control.Lamp
        Lamp_6                          matlab.ui.control.Lamp
        Lamp_9                          matlab.ui.control.Lamp
        IgnitionButton                  matlab.ui.control.StateButton
        StartRecordingDataButton        matlab.ui.control.StateButton
        RetractIgniterButton            matlab.ui.control.StateButton
        PaperIgnitedPanel               matlab.ui.container.Panel
        GridLayout12                    matlab.ui.container.GridLayout
        PaperIgnitedCheckBox            matlab.ui.control.CheckBox
        CenterPanel                     matlab.ui.container.Panel
        LivestreamPanel                 matlab.ui.container.Panel
        Image                           matlab.ui.control.Image
        TabGroup                        matlab.ui.container.TabGroup
        LiveGraphsTab                   matlab.ui.container.Tab
        GridLayout14                    matlab.ui.container.GridLayout
        TabGroup2                       matlab.ui.container.TabGroup
        TemperatureTab                  matlab.ui.container.Tab
        GridLayout15                    matlab.ui.container.GridLayout
        UIAxes                          matlab.ui.control.UIAxes
        RadiativeFluxTab                matlab.ui.container.Tab
        GridLayout15_2                  matlab.ui.container.GridLayout
        UIAxes_2                        matlab.ui.control.UIAxes
        ChangeinWeightTab               matlab.ui.container.Tab
        GridLayout15_3                  matlab.ui.container.GridLayout
        UIAxes2                         matlab.ui.control.UIAxes
        LiveTables                      matlab.ui.container.Tab
        GridLayout16                    matlab.ui.container.GridLayout
        UITable                         matlab.ui.control.Table
        RightPanel                      matlab.ui.container.Panel
        VitalsPanel                     matlab.ui.container.Panel
        GridLayout10                    matlab.ui.container.GridLayout
        PressureSensorPanel             matlab.ui.container.Panel
        GridLayout11                    matlab.ui.container.GridLayout
        ChamberPressureEditFieldLabel   matlab.ui.control.Label
        ChamberPressureEditField        matlab.ui.control.NumericEditField
        psiaLabel                       matlab.ui.control.Label
        Lamp_7                          matlab.ui.control.Lamp
        AmbientTemperatureEditFieldLabel  matlab.ui.control.Label
        AmbientTemperatureEditField     matlab.ui.control.NumericEditField
        CLabel                          matlab.ui.control.Label
        Lamp_8                          matlab.ui.control.Lamp
        MassFlowControllersPanel        matlab.ui.container.Panel
        GridLayout13                    matlab.ui.container.GridLayout
        VolumetricFlowO2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowO2EditField       matlab.ui.control.NumericEditField
        SLPMLabel                       matlab.ui.control.Label
        VolumetricFlowN2EditFieldLabel  matlab.ui.control.Label
        VolumetricFlowN2EditField       matlab.ui.control.NumericEditField
        SLPMLabel_2                     matlab.ui.control.Label
        MassFlowO2EditFieldLabel        matlab.ui.control.Label
        MassFlowO2EditField             matlab.ui.control.NumericEditField
        MassFlowN2EditFieldLabel        matlab.ui.control.Label
        MassFlowN2EditField             matlab.ui.control.NumericEditField
        VolumetricFlowTotEditFieldLabel  matlab.ui.control.Label
        VolumetricFlowTotEditField      matlab.ui.control.NumericEditField
        MassFlowTotalEditFieldLabel     matlab.ui.control.Label
        MassFlowTotalEditField          matlab.ui.control.NumericEditField
        InletVelocityEditFieldLabel     matlab.ui.control.Label
        InletVelocityEditField          matlab.ui.control.NumericEditField
        SLPMLabel_3                     matlab.ui.control.Label
        ULabel_4                        matlab.ui.control.Label
        ULabel_5                        matlab.ui.control.Label
        ULabel_6                        matlab.ui.control.Label
        msLabel                         matlab.ui.control.Label
        UpstreamPressureEditFieldLabel  matlab.ui.control.Label
        UpstreamPressureEditField       matlab.ui.control.NumericEditField
        UpstreamPressureEditField_2Label  matlab.ui.control.Label
        UpstreamPressureEditField_2     matlab.ui.control.NumericEditField
        psiaLabel_3                     matlab.ui.control.Label
        psiaLabel_2                     matlab.ui.control.Label
        StopRunButton                   matlab.ui.control.StateButton
        ConsoleTextAreaLabel            matlab.ui.control.Label
        ConsoleTextArea                 matlab.ui.control.TextArea
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
        twoPanelWidth = 768;
    end

    
    properties (Access = public)
        f % Description
        ar %
        running %
        addrs%
        adc %
        WireReady
        TubeReady
        FlowReady
        ScaleReady
        Tab
        AllData
        Sensors
        Sensor_report
        Pressure_running
        Flow02
        FlowN2
        MFC1_ID
        MFC2_ID
        fullname
        cam
        dataRes
        dir
        s
        line1
        line2
        line3
    end
    
    methods (Access = public)
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            clear; clc;
            app.FlowReady = 0;
            app.TubeReady = 0;
            app.ScaleReady = 0;
            app.WireReady = 0;
            app.addrs = 1;
        end

        % Button pushed function: ChooseParentDirectoryButton
        function ChooseParentDirectoryButtonPushed(app, event)
            selpath =  uigetdir;
            app.FolderSelectedEditField.Value = selpath;
        end

        % Button pushed function: WiringCheckButton
        function WiringCheckButtonPushed(app, event)
            %Change to whatever COM port the Arduino is connected to
            app.s = serialport("COM6",9600); 
            %This is the # of seconds the app waits between measurements
            app.dataRes = 1;
            
            %Initialize webcam -- Change name to whatever cam you are using
            if app.CheckBox_2.Value
                app.cam = webcam('USB2.0 HD UVC WebCam');
            end
            
            %Check wiring                     
            write(app.s,'T',"uint8");  
            pause(3);
            
            txt = "";
            while app.s.BytesAvailable > 0
                b = readline(app.s);
                txt = txt + b;
                app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s",b)];                       
            end
            
            if ~contains(txt, "not")
                %If all connections found it lets you continue.
                app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                                sprintf("%s","All Sensors found!")];
                app.WireReady = 1;
                app.Lamp.Color = 'green';
                    
            else
                %does not turn light green, and clears the serial port
                app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                                sprintf("%s","Not all Sensors found! Check wiring to continue")];
                clear app.s;                              
            end


        end

        % Value changed function: O2molSpinner
        function O2molSpinnerValueChanged(app, event)
            value = app.O2molSpinner.Value;
            if (value<=100) && (value>=0)
                app.N2molSpinner.Value = 100-value;
            end
            
            %Need to calculate the flow for O2 and N2 here
            
            app.FlowReady = 1;
            
        end

        % Button pushed function: ResetScaleButton
        function ResetScaleButtonPushed(app, event)
            write(app.s,'t',"uint8"); 
            pause(3)
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s","Scale Tared")];
            
            app.ScaleReady = 1;
                        
            
        end

        % Value changed function: PaperIgnitedCheckBox
        function PaperIgnitedCheckBoxValueChanged(app, event)
            value = app.PaperIgnitedCheckBox.Value;
            
        end

        % Value changed function: EvacuateChamberButton
        function EvacuateChamberButtonValueChanged(app, event)
            %{
            This is going to initiate the main event loop.
            
            From the loop, all other state buttons will be checked and resulting actions will be exectuted.        
            %}
            value = app.EvacuateChamberButton.Value;
            
            app.running = 1;
            
            %Initialize camera writer
            if app.CheckBox_2.Value
                vidWriter = VideoWriter(sprintf("%s%s",app.fullname,".mp4"));
                open(vidWriter);
            end
            
            %This starts the  arduino's main loop, in which it sends data 
            write(app.s,'B',"uint8"); 
            
            %Initiate Data matrix: [T1,T2,P,T_P,W,L]
            data = [];
            
            %flow is not set yet
            flowS = false;
            
            MAXAMBIENTPRESSURE = 16; %PSIA
            MAXAMBIENTTEMPERATURE = 50; %C
            REFILLPRESSURE = 4.3; %PSIA
            DESIREDPRESSURE1 = 14.5; %PSIA
            DESIREDPRESSURE2 = 15.5; %PSIA
            
            t = seconds(0);            
            i = 0;
            while app.running                
                %Take a snapshot, write it to the mp4 file, and display it
                %in the GUI
                if app.CheckBox_2.Value
                    img = snapshot(app.cam);
                    writeVideo(vidWriter,img);
                    app.Image.ImageSource = img;  
                end
                
                %Read arduino and split data into component parts
                b = readline(app.s);
                a = cellstr(b);
                st = a{1}(1:end-1);
                c = strsplit(st,"|"); 
                
                %Creates Row cell array and full data array
                row = {};
                for j = 1:length(c)
                    row{j} = str2double(c{j});
                    %data(i,j) = row(j);
                end 
                
                %Unpack row into variables
                [T1,T2,P,T_P,W,L] = row{:};                
                               
                %This updates the temperature and pressure fields of the GUI
                app.ChamberPressureEditField.Value = P;
                app.psiaEditField = P;
                app.AmbientTemperatureEditField.Value = T_P;
                
                %This lets you know that the Pressure is low enough to
                %refill the chamber
                if app.Lamp_3.Color(1) == 1          
                    if P < REFILLPRESSURE
                        app.Lamp_3.Color = 'green';
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s","Desired vacuum pressure achieved. May begin refilling chamber")];
                    else
                        pause(app.dataRes);
                        continue;
                    end
                end
                
                %This lets you know if the pressure in the chamber is
                %getting too high
                if P > MAXAMBIENTPRESSURE
                    app.Lamp_7.Color = 'red';
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                        sprintf("%s","*Chamber ambient pressure too high*")];
                end
                
                %This lets you know if the ambient temperature in the chamber is
                %getting too high
                if T_P > MAXAMBIENTTEMPERATURE
                    app.Lamp_8.Color = 'red';
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                        sprintf("%s","*Chamber ambient temperature too high*")];
                end                
                
                if app.RefillChamberButton.Value && ~flowS 
                    %Read MFC and see if settings correct
                    MFC1_Data = readMFC(MFC);
                    MFC2_Data = readMFC(MFC);
                    
                    %If MFC's are not connected, will not let you continue
                    %with the test and prints out a warning
                    if MFC1_Data.pressure < 50 && MFC2_Data.pressure < 50
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s","*Mass Flow Controllers NOT Connected to Gas Lines*")];
                        
                        pause(app.dataRes);
                        continue;
                    elseif MFC1_Data.pressure < 50
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s","*O2 Mass Flow Controller NOT Connected to Gas Lines*")];
                        
                        pause(app.dataRes);
                        continue;
                    elseif MFC2_Data.pressure < 50
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                            sprintf("%s","*N2 Mass Flow Controller NOT Connected to Gas Lines*")];                        
                        
                        pause(app.dataRes);
                        continue;
                    else                       
                        A1,st1 = setFlow(app.flowO2,app.MFC1_ID);
                        A2,st2 = setFlow(app.flowN2,app.MFC2_ID);
                    
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; 
                        sprintf("%s",st1);sprintf("%s",st2)];
                        %Makes sure the flow isn't reset each iteration 
                        flowS = true;
                    end
                elseif ~flowS 
                    %Stop iteration if Flow hasn't been set yet
                    pause(app.dataRes);
                    continue;                        
                end
                
                %Read MFCs
                MFC1_Data = readMFC(MFC);
                MFC2_Data = readMFC(MFC);
                
                %Update MFC Panel on the right
                app.VolumetricFlowO2EditField.Value = MFC1_Data.volumetricFlow;
                app.VolumetricFlowN2EditField.Value = MFC2_Data.volumetricFlow;
                app.VolumetricFlowTotEditField.Value = MFC1_Data.volumetricFlow + MFC2_Data.volumetricFlow;
                app.MassFlowO2EditField.Value = MFC1_Data.massFlow;
                app.MassFlowN2EditField.Value = MFC2_Data.massFlow;
                app.MassFlowTotalEditField.Value = MFC1_Data.massFlow + MFC2_Data.massFlow;
                app.InletVelocityEditField.Value = app.VolumetricFlowTotEditField.Value / (.01 * app.InletAreacm2Spinner.Value);
                app.UpstreamPressureEditField.Value = MFC1_Data.pressure;
                app.UpstreamPressureEditField_2.Value = MFC2_Data.pressure;
                
                %Alerts the user if the pressure heading into the MFC is
                %too low, and if so ends the run.
                if MFC1_Data.pressure < 50
                    app.UpstreamPressureEditField.BackgroundColor = "red";
                    app.StopRunButton.Value = True;
                    app.running = 0;
                end
                if MFC2_Data.pressure <50
                    app.UpstreamPressureEditField_2.BackgroundColor = "red";  
                    app.StopRunButton.Value = True;                    
                    app.running = 0;
                end
                
                    
                %If pressure is within testing range, indicates the chamber
                %is refilled
                if (P > DESIREDPRESSURE1) && (P < DESIREDPRESSURE2)
                    if app.Lamp_4.Color(1) == 1      
                        app.Lamp_4.Color = 'green';
                        
                        app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; sprintf("%s","Desired conditions met. May initiate Test")];
                    end
                else
                    app.Lamp_4.Color = 'red';
                    pause(app.dataRes);
                    continue;
                end 
                
                %Checks if ignition lamp is off and if the button is
                %pressed before telling the arduino to ignite the sample
                % (and maybe put the igniter down)
                if (app.Lamp_5.Color(1) == 1) && (app.IgnitionButton.Value)
                    app.Lamp_5.Color = 'green';
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; sprintf("%s","Ignition starting...")];
                    
                    %write(s,'F',"uint8");
                    write(app.s,'O',"uint8");                    
                end
                
                %This adds the instrument data to the data output array and
                %the dynamic graphs/tables only once the StartRecordingDataButton is pressed
                if app.StartRecordingDataButton.Value
                    if  app.Lamp_9.Color(1) == 1  
                        app.Lamp_9.Color = 'green';
                    end
                    
                    i = i+1;
                    %Adds each line to the data matrix
                    for j = 1:length(c)
                        data(i,j) = row{j};
                    end
                    
                    data(i,length(c) + 1) = app.PaperIgnitedCheckBox.Value;
                    
                    j = length(c)+1;
                    if ~ismatrix(MFC1_Data)
                        data(i,j+1) = MFC1_Data.ID;
                        data(i,j+2) = MFC1_Data.pressure;
                        data(i,j+3) = MFC1_Data.temp;
                        data(i,j+4) = MFC1_Data.volumetricFlow;
                        data(i,j+5) = MFC1_Data.massFlow;
                        data(i,j+6) = MFC1_Data.setPoint;
                        data(i,j+7) = MFC1_Data.gas;               
                    end
                    j = j + 7; %I was lazy
                    if ~ismatrix(MFC2_Data)
                        data(i,j+1) = MFC2_Data.ID;
                        data(i,j+2) = MFC2_Data.pressure;
                        data(i,j+3) = MFC2_Data.temp;
                        data(i,j+4) = MFC2_Data.volumetricFlow;
                        data(i,j+5) = MFC2_Data.massFlow;
                        data(i,j+6) = MFC2_Data.setPoint;
                        data(i,j+7) = MFC2_Data.gas;  
                        data(i,j+8) = MFC2_Data.time;  
                    end
                    
                    
                    %Adds to the graphs/tables
                    if i == 1
                        %If its the first iteration initiates the lines and
                        %adds them to their graphs
                        t_num = seconds(t);%<----Check this 
                        
                        %Initiate Graphs/Tables
                        app.Tab = {t_num, T1 , L, W};
                        app.UITable.Data = [app.Tab];
                        app.line1 = line(app.UIAxes,t,T1);
                        app.line2 = line(app.UIAxes_2,t,L);
                        app.line3 = line(app.UIAxes2,t,W);
                           
                        %Change this accordingly
                        %ylim(app.UIAxes,[3.41,3.43]);
                    else
                        t_num(i) = seconds(t(i));
                
                        app.line1.XData = [app.line1.XData t(i)];
                        app.line2.XData = [app.line2.XData t(i)];
                        app.line3.XData = [app.line3.XData t(i)];
                
                        app.line1.YData = [app.line1.YData T1];
                        app.line2.YData = [app.line2.YData L];
                        app.line3.YData = [app.line3.YData W];
                
                        app.Tab = [app.Tab;{t_num(i),T1 , L, W}];
                        app.UITable.Data = [app.Tab];
                            
                        %Makes sure you only see 60 time points at a time
                        if t<seconds(60)
                            xlim(app.UIAxes,[seconds(0),seconds(60)])
                            xlim(app.UIAxes_2,[seconds(0),seconds(60)])
                            xlim(app.UIAxes2,[seconds(0),seconds(60)])
                        else
                            xlim(app.UIAxes,[t(i)-seconds(60),t(i)])
                            xlim(app.UIAxes_2,[t(i)-seconds(60),t(i)])
                            xlim(app.UIAxes2,[t(i)-seconds(60),t(i)])
                        end           
                    end
                    t(i+1) = t(i) + seconds(app.dataRes);
                end
                
                %Checks if retract igniter lamp is off and if the button is
                %pressed before telling the arduino to cut the ignition circuit
                %and retract the igniter
                if (app.Lamp_6.Color == "red" ) && (app.RetractIgniterButton.Value)
                    app.Lamp_6.Color = 'green';
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; sprintf("%s","Retracting Ignitor...")];
                    write(app.s,'C',"uint8");   
                    %May need to add a pause here 
                    write(app.s,'R',"uint8");
                end   
                
                %This button breaks the event loop and the arduino's event loop. 
                %Button Default is off 
                if app.StopRunButton.Value
                    app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value; sprintf("%s","Stopping all processes...")];
                    app.running = 0;
                    write(app.s,'E',"uint8"); 
                    clear app.s;
                    
                    %Close the vide writer and camera
                    if app.CheckBox_2.Value
                        close(vidWriter);
                        clear app.cam;
                    end
                    
                    %Colate and append all the data, then send it to an
                    %excel file
                    %Create structure with each column as a field, then
                    %creat a table and send it to file.
                    %[T1,T2,P,T_P,W,L]
                    st.T1 = data(:,1);
                    st.T2 = data(:,2);
                    st.P = data(:,3);
                    st.T_P = data(:,4);
                    st.W = data(:,5);
                    st.L = data(:,6);
                    st.Ignited = data(:,7);
                    st.MFC1_ID = data(:,8) ;
                    st.MFC1_pressure = data(:,9);
                    st.MFC1_temp = data(:,10) ;
                    st.MFC1_volumetricFlow = data(:,11);
                    st.MFC1_massFlow = data(:,12);
                    st.MFC1_setPoint = data(:,13);
                    st.MFC1_gas = data(:,14);
                    st.MFC2_ID = data(:,15)  ;
                    st.MFC2_pressure = data(:,16);
                    st.MFC2_temp = data(:,17);
                    st.MFC2_volumetricFlow = data(:,18);
                    st.MFC2_massFlow = data(:,19) ;
                    st.MFC2_setPoint = data(:,20);
                    st.MFC2_gas = data(:,21);
                    t.MFC2_time = data(:,22);
                    
                    app.AllData = table2timetable(struct2table(st),'RowTimes',t.MFC2_time);
                    writetimetable(app.AllData,sprintf("%s.%s",app.fullname, "xlsx"))
                end
                
                %Pause a certain number of seconds
                pause(app.dataRes)
                
            end


        end

        % Button pushed function: ReadyButton
        function ReadyButtonPushed2(app, event)
            %Convert the percentages to fractions
            a = app.O2molSpinner.Value / 100;
            b = app.N2molSpinner.Value / 100;
            
            if app.CODE_NAME2.Value == "Volumetric Flow Rate(SLPM)"               
                %Calculate the volumetric flow rate for both the O2 and N2 MFC's
                app.Flow02 = app.VolumetricFlowRateSLPMSpinner.Value * a;
                app.FlowN2 = app.VolumetricFlowRateSLPMSpinner.Value * b;
            else
                Vdot = app.VolumetricFlowRateSLPMSpinner.Value * app.InletAreacm2Spinner.Value * .01;
                app.Flow02 = Vdot * a;
                app.FlowN2 = Vdot * b;
            end
            
            %Print new flow rates to console            
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s","Flow of O2 set to: ", num2str(app.Flow02), " SLPM")];           
            app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s","Flow of N2 set to: ", num2str(app.FlowN2), " SLPM")];
            
            %Based on the MFC Range/ID given assigns the appropriate ID to
            %the appropriate MFC
            if app.MFC1RangeIDEditField.Value == "A" || app.MFC1RangeIDEditField.Value == "B" ...
                    || app.MFC1RangeIDEditField.Value == "C" || app.MFC1RangeIDEditField.Value == "D"... 
                    || app.MFC1RangeIDEditField.Value == "E" || app.MFC1RangeIDEditField.Value == "F"
                app.MFC1_ID = app.MFC1RangeIDEditField.Value; 
            elseif app.MFC1RangeIDEditField.Value == "50"
                app.MFC1_ID = "A";
            elseif app.MFC1RangeIDEditField.Value == "1"
                app.MFC1_ID = "C";
            elseif app.MFC1RangeIDEditField.Value == "5"
                app.MFC1_ID = "E";
            end
            
            if app.MFC2RangeIDEditField.Value == "A" || app.MFC2RangeIDEditField.Value == "B" ...
                    || app.MFC2RangeIDEditField.Value == "C" || app.MFC2RangeIDEditField.Value == "D"... 
                    || app.MFC2RangeIDEditField.Value == "E" || app.MFC2RangeIDEditField.Value == "F"
                app.MFC2_ID = app.MFC2RangeIDEditField.Value; 
            elseif app.MFC2RangeIDEditField.Value == "50"
                if app.MFC1_ID == "A"
                    app.MFC2_ID = "B";
                else
                    app.MFC2_ID = "A";
                end                
            elseif app.MFC2RangeIDEditField.Value == "1"
                if app.MFC1_ID == "C"
                    app.MFC1_ID = "D";
                else
                    app.MFC1_ID = "C";
                end
            elseif app.MFC2RangeIDEditField.Value == "5"
                if app.MFC1_ID == "E"
                    app.MFC1_ID = "F";
                else
                    app.MFC1_ID = "E";
                end
            end
            
        end

        % Value changed function: TestNameEditField
        function TestNameEditFieldValueChanged(app, event)
            value = app.TestNameEditField.Value;
            
            %Sets the name of all files in the folder
            if ~isempty(app.TestNameEditField.Value)                
                %Make a directory for all the data from the test
                app.dir = mkdir(app.FolderSelectedEditField.Value, value);
                
                app.fullname = sprintf("%s\\%s\\%s",app.FolderSelectedEditField.Value,value,value);
                
                app.ConsoleTextArea.Value = [app.ConsoleTextArea.Value;
                sprintf("%s%s%s%s","Made new directory ", value, " inside ", app.FolderSelectedEditField.Value)];
            
                set(app.RunPanel,'visible','on')
            end
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 3x1 grid
                app.GridLayout.RowHeight = {1040, 1040, 1040};
                app.GridLayout.ColumnWidth = {'1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 1;
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 3;
                app.RightPanel.Layout.Column = 1;
            elseif (currentFigureWidth > app.onePanelWidth && currentFigureWidth <= app.twoPanelWidth)
                % Change to a 2x2 grid
                app.GridLayout.RowHeight = {1040, 1040};
                app.GridLayout.ColumnWidth = {'1x', '1x'};
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = [1,2];
                app.LeftPanel.Layout.Row = 2;
                app.LeftPanel.Layout.Column = 1;
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 2;
            else
                % Change to a 1x3 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {336, '1x', 295};
                app.LeftPanel.Layout.Row = 1;
                app.LeftPanel.Layout.Column = 1;
                app.CenterPanel.Layout.Row = 1;
                app.CenterPanel.Layout.Column = 2;
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 3;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.SetupPanel = uipanel(app.LeftPanel);
            app.SetupPanel.Tooltip = {'Setup a run. Go down in order.'};
            app.SetupPanel.Title = 'Setup';
            app.GridLayout2 = uigridlayout(app.SetupPanel);
            app.BasicChecksPanel = uipanel(app.GridLayout2);
            app.BasicChecksPanel.Title = 'Basic Checks';
            app.GridLayout3 = uigridlayout(app.BasicChecksPanel);
            app.WiringCheckButton = uibutton(app.GridLayout3, 'push');
            app.WiringCheckButton.ButtonPushedFcn = createCallbackFcn(app, @WiringCheckButtonPushed, true);
            app.WiringCheckButton.Tooltip = {'Initiates communication with Arduino, Webcam, and Mass Flow '; 'Controllers. Lampwill turn green if it finds all sensors/actuators '; 'expected. '};
            app.WiringCheckButton.Text = 'Wiring Check';
            app.Lamp = uilamp(app.GridLayout3);
            app.CheckBox = uicheckbox(app.GridLayout3);
            app.CheckBox.Tooltip = {'Manual tubing check. Check once completed.'};
            app.CheckBox.Text = '';
            app.CheckBox_2 = uicheckbox(app.GridLayout3);
            app.CheckBox_2.Tooltip = {'Keep checked if you are using the GUI to record the run, otherwise '; 'uncheck.'};
            app.CheckBox_2.Text = '';
            app.CheckBox_2.Value = true;
            app.TubingCheckedLabel = uilabel(app.GridLayout3);
            app.TubingCheckedLabel.Tooltip = {'Manual tubing check. Check once completed.'};
            app.TubingCheckedLabel.Text = 'Tubing Checked';
            app.LivestreaminAppLabel = uilabel(app.GridLayout3);
            app.LivestreaminAppLabel.Tooltip = {'Keep checked if you are using the GUI to record the run, otherwise '; 'uncheck.'};
            app.LivestreaminAppLabel.Text = 'Livestream in App';
            app.FlowSettingsPanel = uipanel(app.GridLayout2);
            app.FlowSettingsPanel.Title = 'Flow Settings';
            app.GridLayout7 = uigridlayout(app.FlowSettingsPanel);
            app.O2molSpinnerLabel = uilabel(app.GridLayout7);
            app.O2molSpinnerLabel.Text = 'O2 mol%';
            app.O2molSpinner = uispinner(app.GridLayout7);
            app.O2molSpinner.ValueChangedFcn = createCallbackFcn(app, @O2molSpinnerValueChanged, true);
            app.O2molSpinner.Tooltip = {'Input desired mole/volume percent O2.'};
            app.O2molSpinner.Value = 35;
            app.N2molSpinnerLabel = uilabel(app.GridLayout7);
            app.N2molSpinnerLabel.Text = 'N2 mol%';
            app.N2molSpinner = uispinner(app.GridLayout7);
            app.N2molSpinner.Limits = [0 100];
            app.N2molSpinner.Editable = 'off';
            app.N2molSpinner.Tooltip = {'Mole/volume % N2 is calculated from O2 %'};
            app.N2molSpinner.Value = 65;
            app.MFC1RangeIDEditFieldLabel = uilabel(app.GridLayout7);
            app.MFC1RangeIDEditFieldLabel.Text = 'MFC 1 Range/ID';
            app.MFC1RangeIDEditField = uieditfield(app.GridLayout7, 'text');
            app.MFC1RangeIDEditField.Tooltip = {'Input either the letter name(A-E) or fullscale range of flow (1,5,or 50 SLPM) for the '; 'O2 MFC.'};
            app.MFC1RangeIDEditField.Value = '50';
            app.MFC2RangeIDEditFieldLabel = uilabel(app.GridLayout7);
            app.MFC2RangeIDEditFieldLabel.Text = 'MFC 2 Range/ID';
            app.MFC2RangeIDEditField = uieditfield(app.GridLayout7, 'text');
            app.MFC2RangeIDEditField.Tooltip = {'Input either the letter name(A-E) or fullscale range of flow (1,5,or 50 SLPM) for the '; 'N2 MFC.'};
            app.MFC2RangeIDEditField.Value = 'B';
            app.ReadyButton = uibutton(app.GridLayout7, 'push');
            app.ReadyButton.ButtonPushedFcn = createCallbackFcn(app, @ReadyButtonPushed2, true);
            app.ReadyButton.Tooltip = {'Click once all the abovehas been filled out.'};
            app.ReadyButton.Text = 'Ready';
            app.InletAreacm2SpinnerLabel = uilabel(app.GridLayout7);
            app.InletAreacm2SpinnerLabel.Text = 'Inlet Area(cm^2)';
            app.InletAreacm2Spinner = uispinner(app.GridLayout7);
            app.InletAreacm2Spinner.Tooltip = {'The inlet area is preset but is editable if need be.'};
            app.InletAreacm2Spinner.Value = 5;
            app.CODE_NAME2 = uidropdown(app.GridLayout7);
            app.CODE_NAME2.Items = {'Velocity(m/s)', 'Volumetric Flow Rate(SLPM)', ''};
            app.CODE_NAME2.Editable = 'on';
            app.CODE_NAME2.Tooltip = {'Pick whether you are specifying volumetric flow rate(SLPM) or '; 'velocity(m/s).'};
            app.CODE_NAME2.Value = 'Volumetric Flow Rate(SLPM)';
            app.VolumetricFlowRateSLPMSpinner = uispinner(app.GridLayout7);
            app.VolumetricFlowRateSLPMSpinner.Step = 0.1;
            app.VolumetricFlowRateSLPMSpinner.Tooltip = {'Input either volumetric flow rate or velocity, depending on what '; 'you chose in the box to the left.'};
            app.WeightScaleResetPanel = uipanel(app.GridLayout2);
            app.WeightScaleResetPanel.Title = 'Weight Scale Reset';
            app.GridLayout5 = uigridlayout(app.WeightScaleResetPanel);
            app.ResetScaleButton = uibutton(app.GridLayout5, 'push');
            app.ResetScaleButton.ButtonPushedFcn = createCallbackFcn(app, @ResetScaleButtonPushed, true);
            app.ResetScaleButton.Tooltip = {'Tare the scale.'};
            app.ResetScaleButton.Text = 'Reset Scale';
            app.DataTranscriptionPanel = uipanel(app.GridLayout2);
            app.DataTranscriptionPanel.Title = 'Data Transcription';
            app.GridLayout9 = uigridlayout(app.DataTranscriptionPanel);
            app.ChooseParentDirectoryButton = uibutton(app.GridLayout9, 'push');
            app.ChooseParentDirectoryButton.ButtonPushedFcn = createCallbackFcn(app, @ChooseParentDirectoryButtonPushed, true);
            app.ChooseParentDirectoryButton.Tooltip = {'Choose parent directory for test. '};
            app.ChooseParentDirectoryButton.Text = 'Choose Parent Directory';
            app.FolderSelectedEditFieldLabel = uilabel(app.GridLayout9);
            app.FolderSelectedEditFieldLabel.Text = 'Folder Selected:';
            app.FolderSelectedEditField = uieditfield(app.GridLayout9, 'text');
            app.TestNameEditFieldLabel = uilabel(app.GridLayout9);
            app.TestNameEditFieldLabel.Tooltip = {'Enter name of run and hit enter. This will create a subdirectory '; 'named for the test within the parent directory chosen above. All '; 'file created for the test will share the test name as well. '; 'NOTES: '; '1. Dont include special charaacters or spaces in the name. '; '2. Underscores may be exchanged for spaces.'};
            app.TestNameEditFieldLabel.Text = 'Test Name:';
            app.TestNameEditField = uieditfield(app.GridLayout9, 'text');
            app.TestNameEditField.ValueChangedFcn = createCallbackFcn(app, @TestNameEditFieldValueChanged, true);
            app.TestNameEditField.Tooltip = {'Enter name of run and hit enter. This will create a subdirectory '; 'named for the test within the parent directory chosen above. All '; 'file created for the test will share the test name as well. '; 'NOTES: '; '1. Dont include special charaacters or spaces in the name. '; '2. Underscores may be exchanged for spaces.'};
            app.RunPanel = uipanel(app.LeftPanel);
            app.RunPanel.Title = 'Run';
            app.GridLayout17 = uigridlayout(app.RunPanel);
            app.ChamberBackfillPanel = uipanel(app.GridLayout17);
            app.ChamberBackfillPanel.Title = 'Chamber Backfill';
            app.GridLayout6 = uigridlayout(app.ChamberBackfillPanel);
            app.psiaEditFieldLabel = uilabel(app.GridLayout6);
            app.psiaEditFieldLabel.Tooltip = {'Chamber Pressure'};
            app.psiaEditFieldLabel.Text = 'psia';
            app.psiaEditField = uieditfield(app.GridLayout6, 'numeric');
            app.psiaEditField.Tooltip = {'Chamber Pressure'};
            app.PressureLabel = uilabel(app.GridLayout6);
            app.PressureLabel.Tooltip = {'Chamber Pressure'};
            app.PressureLabel.Text = 'Pressure:';
            app.Lamp_3 = uilamp(app.GridLayout6);
            app.Lamp_3.Tooltip = {'Click once you begin evacuating the chamber. '; 'This initiates all sensors readings and the livestream.'; 'Chamber evacuation itself is manual.'; 'Lamp will turn green once an appropriate vacuum pressure has been reached.'};
            app.Lamp_4 = uilamp(app.GridLayout6);
            app.Lamp_4.Tooltip = {'This starts the mass flow controllers. Lamp will turn green once '; 'the chamber pressure is within range for the test. '; 'NOTES: '; '1. Mass Flow Controllers will not start if they don''t sense enough '; 'upstream pressure, preventing overheating and damage. '};
            app.EvacuateChamberButton = uibutton(app.GridLayout6, 'state');
            app.EvacuateChamberButton.ValueChangedFcn = createCallbackFcn(app, @EvacuateChamberButtonValueChanged, true);
            app.EvacuateChamberButton.Tooltip = {'Click once you begin evacuating the chamber. '; 'This initiates all sensors readings and the livestream.'; 'Chamber evacuation itself is manual.'; 'Lamp will turn green once an appropriate vacuum pressure has been reached.'};
            app.EvacuateChamberButton.Text = 'Evacuate Chamber';
            app.RefillChamberButton = uibutton(app.GridLayout6, 'state');
            app.RefillChamberButton.Tooltip = {'This starts the mass flow controllers. Lamp will turn green once '; 'the chamber pressure is within range for the test. '; 'NOTES: '; '1. Mass Flow Controllers will not start if they don''t sense enough '; 'upstream pressure, preventing overheating and damage. '};
            app.RefillChamberButton.Text = 'Refill Chamber';
            app.StartRunPanel = uipanel(app.GridLayout17);
            app.StartRunPanel.Title = 'Start Run';
            app.GridLayout8 = uigridlayout(app.StartRunPanel);
            app.Lamp_5 = uilamp(app.GridLayout8);
            app.Lamp_5.Tooltip = {'Sets off the ignition circuit. Lamp will turn green when set off.'};
            app.Lamp_6 = uilamp(app.GridLayout8);
            app.Lamp_6.Tooltip = {'Closes the ignition ciircuit and retracts the ignitor arm.'};
            app.Lamp_9 = uilamp(app.GridLayout8);
            app.Lamp_9.Tooltip = {'Starts tracking data and displays it in the live graphs and tables '; 'in the center console area. Once the "Stop Run" button has '; 'been pressed, sends all data to excel as a timetable.'; 'NOTES:'; '1. This is a toggle switch, so data will stop being tracked if you  '; 'hit it twice.'};
            app.IgnitionButton = uibutton(app.GridLayout8, 'state');
            app.IgnitionButton.Tooltip = {'Sets off the ignition circuit. Lamp will turn green when set off.'};
            app.IgnitionButton.Text = 'Ignition';
            app.StartRecordingDataButton = uibutton(app.GridLayout8, 'state');
            app.StartRecordingDataButton.Tooltip = {'Starts tracking data and displays it in the live graphs and tables '; 'in the center console area. Once the "Stop Run" button has '; 'been pressed, sends all data to excel as a timetable.'; 'NOTES:'; '1. This is a toggle switch, so data will stop being tracked if you  '; 'hit it twice.'};
            app.StartRecordingDataButton.Text = 'Start Recording Data';
            app.RetractIgniterButton = uibutton(app.GridLayout8, 'state');
            app.RetractIgniterButton.Tooltip = {'Closes the ignition ciircuit and retracts the ignitor arm.'};
            app.RetractIgniterButton.Text = 'Retract Igniter';
            app.PaperIgnitedPanel = uipanel(app.GridLayout17);
            app.PaperIgnitedPanel.Tooltip = {'Manually check the "Paper Ignited"  checkbox. It will be '; 'displayed in the excel sheet'};
            app.PaperIgnitedPanel.Title = 'Paper Ignited';
            app.GridLayout12 = uigridlayout(app.PaperIgnitedPanel);
            app.PaperIgnitedCheckBox = uicheckbox(app.GridLayout12);
            app.PaperIgnitedCheckBox.ValueChangedFcn = createCallbackFcn(app, @PaperIgnitedCheckBoxValueChanged, true);
            app.PaperIgnitedCheckBox.Text = 'Paper Ignited';
            app.CenterPanel = uipanel(app.GridLayout);
            app.LivestreamPanel = uipanel(app.CenterPanel);
            app.LivestreamPanel.Tooltip = {'The livestream will appear here if specified.'};
            app.LivestreamPanel.Title = 'Livestream';
            app.Image = uiimage(app.LivestreamPanel);
            app.TabGroup = uitabgroup(app.CenterPanel);
            app.TabGroup.Tooltip = {'Toggle between live tables and graphs. These will be populated '; 'once you press the "Start Recording Data" button.'};
            app.LiveGraphsTab = uitab(app.TabGroup);
            app.LiveGraphsTab.Title = 'Live Graphs';
            app.GridLayout14 = uigridlayout(app.LiveGraphsTab);
            app.TabGroup2 = uitabgroup(app.GridLayout14);
            app.TemperatureTab = uitab(app.TabGroup2);
            app.TemperatureTab.Title = 'Temperature';
            app.GridLayout15 = uigridlayout(app.TemperatureTab);
            app.UIAxes = uiaxes(app.GridLayout15);
            title(app.UIAxes, 'Temperature vs Time')
            xlabel(app.UIAxes, 't(s)')
            ylabel(app.UIAxes, 'T(C)')
            app.UIAxes.YTick = [0 0.2 0.4 0.6 0.8 1];
            app.RadiativeFluxTab = uitab(app.TabGroup2);
            app.RadiativeFluxTab.Title = 'Radiative Flux';
            app.GridLayout15_2 = uigridlayout(app.RadiativeFluxTab);
            app.UIAxes_2 = uiaxes(app.GridLayout15_2);
            title(app.UIAxes_2, 'Radiative Flux vs Time')
            xlabel(app.UIAxes_2, 't(s)')
            ylabel(app.UIAxes_2, 'Radiaitive Flux(mW/cm^2)')
            app.ChangeinWeightTab = uitab(app.TabGroup2);
            app.ChangeinWeightTab.Title = 'Change in Weight';
            app.GridLayout15_3 = uigridlayout(app.ChangeinWeightTab);
            app.UIAxes2 = uiaxes(app.GridLayout15_3);
            title(app.UIAxes2, 'Change in Weight vs Time')
            xlabel(app.UIAxes2, 't(s)')
            ylabel(app.UIAxes2, 'Change in Weight (g)')
            app.LiveTables = uitab(app.TabGroup);
            app.LiveTables.Title = 'Live Tables';
            app.GridLayout16 = uigridlayout(app.LiveTables);
            app.UITable = uitable(app.GridLayout16);
            app.UITable.ColumnName = {'Time'; 'Temperature'; 'Radiative Flux'; 'Change in Weight'};
            app.UITable.RowName = {};
            app.RightPanel = uipanel(app.GridLayout);
            app.VitalsPanel = uipanel(app.RightPanel);
            app.VitalsPanel.Tooltip = {'Pressure sensor and mass flow controller readings displayed here.'; 'NOTES:'; '1. "Chamber Pressure" and "Ambient Temperature" lamps will '; 'turn red if they approach dangerous levels that would damage '; 'the chamber and/or sensors. '};
            app.VitalsPanel.Title = 'Vitals';
            app.GridLayout10 = uigridlayout(app.VitalsPanel);
            app.PressureSensorPanel = uipanel(app.GridLayout10);
            app.PressureSensorPanel.Title = 'Pressure Sensor';
            app.GridLayout11 = uigridlayout(app.PressureSensorPanel);
            app.ChamberPressureEditFieldLabel = uilabel(app.GridLayout11);
            app.ChamberPressureEditFieldLabel.Text = 'Chamber Pressure';
            app.ChamberPressureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.psiaLabel = uilabel(app.GridLayout11);
            app.psiaLabel.Text = 'psia';
            app.Lamp_7 = uilamp(app.GridLayout11);
            app.AmbientTemperatureEditFieldLabel = uilabel(app.GridLayout11);
            app.AmbientTemperatureEditFieldLabel.Text = 'Ambient Temperature';
            app.AmbientTemperatureEditField = uieditfield(app.GridLayout11, 'numeric');
            app.CLabel = uilabel(app.GridLayout11);
            app.CLabel.Text = 'C';
            app.Lamp_8 = uilamp(app.GridLayout11);
            app.MassFlowControllersPanel = uipanel(app.GridLayout10);
            app.MassFlowControllersPanel.Title = 'Mass Flow Controllers';
            app.GridLayout13 = uigridlayout(app.MassFlowControllersPanel);
            app.VolumetricFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowO2EditFieldLabel.Text = 'Volumetric Flow O2';
            app.VolumetricFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel = uilabel(app.GridLayout13);
            app.SLPMLabel.Text = 'SLPM';
            app.VolumetricFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowN2EditFieldLabel.Text = 'Volumetric Flow N2';
            app.VolumetricFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel_2 = uilabel(app.GridLayout13);
            app.SLPMLabel_2.Text = 'SLPM';
            app.MassFlowO2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowO2EditFieldLabel.Text = 'Mass Flow O2';
            app.MassFlowO2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowN2EditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowN2EditFieldLabel.Text = 'Mass Flow N2';
            app.MassFlowN2EditField = uieditfield(app.GridLayout13, 'numeric');
            app.VolumetricFlowTotEditFieldLabel = uilabel(app.GridLayout13);
            app.VolumetricFlowTotEditFieldLabel.Text = 'Volumetric Flow Tot.';
            app.VolumetricFlowTotEditField = uieditfield(app.GridLayout13, 'numeric');
            app.MassFlowTotalEditFieldLabel = uilabel(app.GridLayout13);
            app.MassFlowTotalEditFieldLabel.Text = 'Mass Flow Total';
            app.MassFlowTotalEditField = uieditfield(app.GridLayout13, 'numeric');
            app.InletVelocityEditFieldLabel = uilabel(app.GridLayout13);
            app.InletVelocityEditFieldLabel.Text = 'Inlet Velocity';
            app.InletVelocityEditField = uieditfield(app.GridLayout13, 'numeric');
            app.SLPMLabel_3 = uilabel(app.GridLayout13);
            app.SLPMLabel_3.Text = 'SLPM';
            app.ULabel_4 = uilabel(app.GridLayout13);
            app.ULabel_4.Text = 'U';
            app.ULabel_5 = uilabel(app.GridLayout13);
            app.ULabel_5.Text = 'U';
            app.ULabel_6 = uilabel(app.GridLayout13);
            app.ULabel_6.Text = 'U';
            app.msLabel = uilabel(app.GridLayout13);
            app.msLabel.Text = 'm/s';
            app.UpstreamPressureEditFieldLabel = uilabel(app.GridLayout13);
            app.UpstreamPressureEditFieldLabel.Text = 'Upstream Pressure';
            app.UpstreamPressureEditField = uieditfield(app.GridLayout13, 'numeric');
            app.UpstreamPressureEditField_2Label = uilabel(app.GridLayout13);
            app.UpstreamPressureEditField_2Label.Text = 'Upstream Pressure';
            app.UpstreamPressureEditField_2 = uieditfield(app.GridLayout13, 'numeric');
            app.psiaLabel_3 = uilabel(app.GridLayout13);
            app.psiaLabel_3.Text = 'psia';
            app.psiaLabel_2 = uilabel(app.GridLayout13);
            app.psiaLabel_2.Text = 'psia';
            app.StopRunButton = uibutton(app.RightPanel, 'state');
            app.StopRunButton.Tooltip = {'Stops the run, disconnects all sensors, and sends data acquired '; 'to file. '};
            app.StopRunButton.Text = 'Stop Run';
            app.ConsoleTextAreaLabel = uilabel(app.RightPanel);
            app.ConsoleTextAreaLabel.Text = 'Console:';
            app.ConsoleTextArea = uitextarea(app.RightPanel);
            app.ConsoleTextArea.Editable = 'off';
            app.ConsoleTextArea.Tooltip = {'Displays updates.'};
            app.ConsoleTextArea.Value = {'Welcome!'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NASA_Test_1_MK2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end